classdef proyecto_metodos_numericos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        GraficarButton                 matlab.ui.control.Button
        PasodelejexEditFieldLabel      matlab.ui.control.Label
        EditField_paso_ejex            matlab.ui.control.NumericEditField
        LmiteSuperiorejex_Label        matlab.ui.control.Label
        EditField_lim_sup_x            matlab.ui.control.NumericEditField
        LmiteInferiorejex_Label        matlab.ui.control.Label
        EditField_lim_inf_x            matlab.ui.control.NumericEditField
        ExtremofEditField_Label        matlab.ui.control.Label
        ExtremofEditField              matlab.ui.control.NumericEditField
        XlEditField_Label              matlab.ui.control.Label
        XlEditField                    matlab.ui.control.NumericEditField
        X0EditField_Label              matlab.ui.control.Label
        X0EditField                    matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
        SolucionarButton               matlab.ui.control.Button
        ExportarSolucinaExcelButton    matlab.ui.control.Button
        SistemadeEcuacionesCheckBox    matlab.ui.control.CheckBox
        EcuacionEditFieldLabel         matlab.ui.control.Label
        EcuacionEditField              matlab.ui.control.EditField
        AgregarButton                  matlab.ui.control.Button
        MetodoSEDropDown_Label         matlab.ui.control.Label
        MetodoSEDropDown               matlab.ui.control.DropDown
        EaEditField_Label              matlab.ui.control.Label
        EaEditField                    matlab.ui.control.NumericEditField
        XuEditField_Label              matlab.ui.control.Label
        XuEditField                    matlab.ui.control.NumericEditField
        ExtremoiEditField_Label        matlab.ui.control.Label
        ExtremoiEditField              matlab.ui.control.NumericEditField
        MetodoDropDown_Label           matlab.ui.control.Label
        MetodoDropDown                 matlab.ui.control.DropDown
        FuncionEditField_Label         matlab.ui.control.Label
        FuncionEditField               matlab.ui.control.EditField
        CriteriodeParoDropDown_Label   matlab.ui.control.Label
        CriteriodeParoDropDown         matlab.ui.control.DropDown
        ValorCriteParoEditField_Label  matlab.ui.control.Label
        ValorCriteParoEditField        matlab.ui.control.NumericEditField
        EcuacionesUITable              matlab.ui.control.Table
        hEditField_Label               matlab.ui.control.Label
        hEditField                     matlab.ui.control.NumericEditField
        XiEditField_Label              matlab.ui.control.Label
        XiEditField                    matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        funcionString  = '';      % Texto de la función
        funcionReal         % Funcion real
        
        % Variables necesitadas como input del usuario
        xu
        xl
        xo
        valor_i             % Valor inicial del intervalo
        valor_f             % Valor final del intervalo
        
        iterMax
        
        
        lim_inf_x            % Valor inicial de la grafica
        lim_sup_x            % Valor final de la grafica
        
        resultado 
        resultado2
        
        % Valores globales
        iter
        crite_paro_valor
    end

    methods (Access = private)
        
        function crearFuncion(app, funcionEditField)
            %crea la funcion
            try 
                syms x;
                app.funcionTexto = funcionEditField;
                if (funcionEditField ~= "")
                    helpdlg('Datos agregados correctamente', 'Información');
                    app.bandera = 1;
                
                else
                    throw(exception)
                end
                
            catch  exception
                message = sprintf('La función no puede ser creada');
                uialert(app.UIFigure, message,'Error');
            end
        end
    
        
        function Bisect(app, f_string,  xl, xu, xr, ea, es, imax)
            syms x;
            app.iter = 0;
            f = inline(f_string);          
            fl = f(xl);
            tolerancia = ea;
            error = es;
            while 1
                if strcmp(app.crite_paro_valor,'Iteraciones')
                    if app.iter >= imax
                        break;
                    end
                elseif strcmp(app.crite_paro_valor,'Error Absoluto')
                    if es <= ea
                        break
                    end
                end
                % (strcmp(app.crite_paro_valor,'Iteraciones') && app.iter < imax) || ea < es
                xrold = xr;
                xr = (xl + xu) /2 ;
                fr = f(xr);
                app.iter = app.iter + 1;
                
                if xr ~= 0
                    ea = (abs(xr - xrold)/xr ) * 100  ;  
                end
                
                test = fl * fr;
                if test < 0
                   xu = xr ;
                
                elseif test > 0
                    xl = xr;
                    fl = fr;
                else
                    ea = 0 ;  
                
                end
                app.agregarTablaBisec(app.iter, xl, xu, xr, f(xl), fr, fr * f(xl), ea)
            end
            app.resultado = xr;
            
        end
    
    
        function Fixpt(app, f_string, x0, ea, es, imax)
            syms x;
            f = inline(f_string); 
            xr = x0;
            app.iter = 0;
            while 1
                if strcmp(app.crite_paro_valor,'Iteraciones')
                    if app.iter >= imax
                        break;
                    end
                elseif strcmp(app.crite_paro_valor,'Error Absoluto')
                    if es <= ea
                        break
                    end
                end
                % (strcmp(app.crite_paro_valor,'Iteraciones') && app.iter < imax) || ea < es
                xrold = xr;
                xr = f(xr)/diff(f(xr),x);
                app.iter = app.iter + 1;
                if xr ~= 0
                    ea = abs(xr - xrold / xr) * 100;
                end
                app.agregarTablaFixpt(app.iter, xr, e);  
            end
            app.resultado = xr;
        end
        
        function NewtonRaphson(app, f_string, x0, ea, es, imax)
            tolerancia = ea;
            error = es;
            syms x;
            app.iter = 0; %iteraciones
            
            % New
            f1 = str2func(['@(x)' f_string]);
            df = diff(f1, x); %diferencial 
            df = inline(df);
            f = inline(f_string);
            
            while 1
                if strcmp(app.crite_paro_valor,'Iteraciones')
                    if app.iter >= imax
                        break;
                    end
                elseif strcmp(app.crite_paro_valor,'Error Absoluto')
                    if error <= tolerancia
                        break
                    end
                end
                % (strcmp(app.crite_paro_valor,'Iteraciones') && app.iter < imax) || ea < es
                %( strcmp(app.crite_paro_valor,'Iteraciones') && app.iter < imax) || (error > tolerancia)
                
                xn = x0 - f(x0)/df(x0);
                error = abs((xn-x0)/xn);
                x0 = xn;
                app.iter = app.iter + 1;
                app.agregarTablaRaph(app.iter, x0, error); 
                  
            end
            app.resultado = f(x0);

        end
    
        function TrapecioCompuesto(app, f_string, a, b, imax)
            syms x;
            syms y;
            f = inline(f_string);
            X0 = f(a);
            Xr = f(b);
            h = (b-a)/imax;
            fs = 0;
            app.resultado = 0;
            while app.iter < imax - 1
                x_i = a + h*app.iter;
                fs = fs + f(x_i);
                app.iter = app.iter + 1;
                app.agregarTablaIntegral(iteraciones, x_i, f(x_i), fs, X0, Xr, app.resultado)
            end
            app.resultado = 0.5*h*(X0+2*fs+Xr);
            app.agregarTablaIntegral(iteraciones, xi, fxi, fs, X0, Xr, app.resultado)
        end
    
        function Euler(app, f_string, xi, yi, a, b, h)
            syms x;
            f = inline(f_string);
            
            while xi <= b
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                syms X;
                syms Y;    	
                k = feval(f, xi, yi);
                df = matlabFunction(diff(f(X, Y),X));
                valor_exacto = df(xi, yi);
                error = abs((valor_exacto - yi)./valor_exacto);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.agregarTablaEuler(app, xi, yi, valor_exacto, k, error)
                
                % Modificar Valores
                xi = xi + h;
                yi = yi + (k.*h);
                
            end
            
            
        end


    %agregarTablaEuler(app, xi, yi, valor_exacto, k, error)
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
        function agregarTablaBisec(app, iter, Xl, Xu, Xr, f_Xl, f_Xr, f_XrXl, ea)
            oldData = get(app.UITable,'Data');
            newData = { iter, Xl, Xu, Xr, f_Xl, f_Xr, f_XrXl, ea;};
            newData = [oldData; newData];
            
            app.UITable.Data = newData;
            
        end
        
        function agregarTablaRaph(app, iteraciones, x0, e)
            oldData = get(app.UITable,'Data');
            newData = { iteraciones, x0, e;};
            newData = [oldData; newData];
            
            app.UITable.Data = newData;
            
        end
    
        function agregarTablaFixpt(app, iteraciones, xr, ea)
            oldData = get(app.UITable,'Data');
            newData = { iteraciones, xr, ea;};
            newData = [oldData; newData];
            
            app.UITable.Data = newData;
            
        end
    
        function agregarTablaIntegral(app, iteraciones, xi, fxi, fs, fa, fb, resultado)
            oldData = get(app.UITable,'Data');
            newData = { iteraciones, xi, fxi, fs, fa, fb, resultado;};
            newData = [oldData; newData];
            
            app.UITable.Data = newData;
            
        end
    
        function agregarTablaEuler(app, xi, yi, valor_exacto, k, error)
            oldData = get(app.UITable,'Data');
            newData = { xi, yi, valor_exacto, k, error;};
            newData = [oldData; newData];
            
            app.UITable.Data = newData;
            
        end
    
        function resetTable(app, type)
            data = {};
            app.UITable.Data = data;
            if strcmp(type,'Newton-Raphson')
                app.UITable.ColumnName = {'Iteracion'; 'Valor Aproximado'; 'Error Aproximado'};
            elseif strcmp(type,'Biseccion')
                app.UITable.ColumnName = {'Iteracion'; 'Xl'; 'Xu'; 'Xr'; 'f(Xl)'; 'f(Xr)'; 'f(Xl)*f(Xr)'; 'Ea'};
            elseif strcmp(type,'Punto Fijo')
                app.UITable.ColumnName = {'Iteracion'; 'Xr'; 'Error Aproximado'};
            elseif strcmp(type,'Integral')
                app.UITable.ColumnName = {'Iteracion'; 'Xi'; 'f(Xi)'; 'fs';'f(a)'; 'f(b)' ;'Resultado';};
            elseif strcmp(type,'Sistema de Ecuaciones')
                app.UITable.ColumnName = {'Resultado';};
            end
        end
        
        function generarGrafico (app, xi, xf, input)
            try 
                %syms x y;
                f = str2func(['@(x)' input]);
                x = linspace(xi, xf);
                plot(app.UIAxes, x, f(x));
                hold(app.UIAxes);
            catch
                message = sprintf('Verifique utilizar .^ y ./ en la función');
                uialert(app.UIFigure, message,'Error - Función');
            end
        end
    
        function f_sirve = funcionSirve(app, f_string)
            f_sirve = 0;
            
            try
                syms x;
                f = str2func(['@(x) ',f_string]);

                f_sirve = 1;
                fl = f(1);
                f = inline(f_string);
                f2 = f(1);
                if strcmp(f_string, '')
                    f_sirve = 0;
                end
            catch
                f_sirve = 0;
            end


        end


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Components
            app.EcuacionEditField.Visible = 'off';
            app.AgregarButton.Visible = 'off';
            app.EcuacionesUITable.Visible = 'off';
            app.MetodoSEDropDown.Visible = 'off';
            
            %Labels
            app.EcuacionEditFieldLabel.Visible = 'off';
            app.MetodoSEDropDown_Label.Visible = 'off';
            
            %Variables globales
            app.crite_paro_valor = app.CriteriodeParoDropDown.Value;
            if strcmp(app.MetodoDropDown.Value,'Biseccion')
                % On
                app.XuEditField.Visible = 'on';
                app.XuEditField_Label.Visible = 'on';
                app.XlEditField.Visible = 'on';
                app.XlEditField_Label.Visible = 'on';
                app.X0EditField.Visible = 'on';
                app.X0EditField_Label.Visible = 'on';
                app.EaEditField.Visible = 'on';
                app.EaEditField_Label.Visible = 'on';
                % Off
                app.ExtremoiEditField.Visible = 'off';
                app.ExtremoiEditField_Label.Visible = 'off';
                app.ExtremofEditField.Visible = 'off';
                app.ExtremofEditField_Label.Visible = 'off';
                
                app.hEditField.Visible = 'off';
                app.hEditField_Label.Visible = 'off';
                app.XiEditField_Label.Visible = 'off';
                app.XiEditField.Visible = 'off';
            end
        end

        % Value changed function: SistemadeEcuacionesCheckBox
        function SistemadeEcuacionesCheckBoxValueChanged(app, event)
            value = app.SistemadeEcuacionesCheckBox.Value;
            if value
                app.EcuacionEditField.Visible = 'on';
                app.AgregarButton.Visible = 'on';
                app.EcuacionesUITable.Visible = 'on';
                app.MetodoSEDropDown.Visible = 'on';
                    %Labels
                app.EcuacionEditFieldLabel.Visible = 'on';
                
                app.MetodoSEDropDown_Label.Visible = 'on';
                
                app.resetTable('Sistema de Ecuaciones');
                
                % OFF
                app.X0EditField.Visible = 'off';
                app.X0EditField_Label.Visible = 'off';
                app.EaEditField.Visible = 'off';
                app.EaEditField_Label.Visible = 'off';
                app.XuEditField.Visible = 'off';
                app.XuEditField_Label.Visible = 'off';
                app.XlEditField.Visible = 'off';
                app.XlEditField_Label.Visible = 'off';
                app.ExtremoiEditField.Visible = 'off';
                app.ExtremoiEditField_Label.Visible = 'off';
                app.ExtremofEditField.Visible = 'off';
                app.ExtremofEditField_Label.Visible = 'off';
                app.CriteriodeParoDropDown.Enable = true;
                
                app.MetodoDropDown_Label.Visible = 'off';
                app.MetodoDropDown.Visible = 'off';
                app.FuncionEditField.Visible = 'off';
                app.FuncionEditField_Label.Visible = 'off';
                
                app.CriteriodeParoDropDown.Visible = 'off';
                app.CriteriodeParoDropDown_Label.Visible = 'off';
                
                app.ValorCriteParoEditField.Visible = 'off';
                app.ValorCriteParoEditField_Label.Visible = 'off';
                
                app.ExportarSolucinaExcelButton.Visible = 'off';
                
                app.hEditField.Visible = 'off';
                app.hEditField_Label.Visible = 'off';
                app.XiEditField_Label.Visible = 'off';
                app.XiEditField.Visible = 'off';
                
                
            else
                app.EcuacionEditField.Visible = 'off';
                app.AgregarButton.Visible = 'off';
                app.EcuacionesUITable.Visible = 'off';
                app.MetodoSEDropDown.Visible = 'off';
                %Labels
                app.EcuacionEditFieldLabel.Visible = 'off';
                app.MetodoSEDropDown_Label.Visible = 'off';
                
                app.X0EditField.Visible = 'on';
                app.X0EditField_Label.Visible = 'on';
                app.EaEditField.Visible = 'on';
                app.EaEditField_Label.Visible = 'on';
                app.XuEditField.Visible = 'on';
                app.XuEditField_Label.Visible = 'on';
                app.XlEditField.Visible = 'on';
                app.XlEditField_Label.Visible = 'on';
                app.ExtremoiEditField.Visible = 'on';
                app.ExtremoiEditField_Label.Visible = 'on';
                app.ExtremofEditField.Visible = 'on';
                app.ExtremofEditField_Label.Visible = 'on';
                app.CriteriodeParoDropDown.Enable = true;
                
                % dropdowns
                app.MetodoDropDown_Label.Visible = 'on';
                app.MetodoDropDown.Visible = 'on';
                app.FuncionEditField.Visible = 'on';
                app.FuncionEditField_Label.Visible = 'on';
                
                app.CriteriodeParoDropDown.Visible = 'on';
                app.CriteriodeParoDropDown_Label.Visible = 'on';
                
                app.ValorCriteParoEditField.Visible = 'on';
                app.ValorCriteParoEditField_Label.Visible = 'on';
                
                app.ExportarSolucinaExcelButton.Visible = 'on';


            end
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
           app.lim_inf_x = app.EditField_lim_inf_x.Value;
            app.lim_sup_x = app.EditField_lim_sup_x.Value;
            if (app.lim_inf_x < app.lim_sup_x)
                xi = app.lim_inf_x;
                xf = app.lim_sup_x;
                app.generarGrafico( xi, xf, app.FuncionEditField.Value);
            else
                message = sprintf('Error, verificar valor incial < valor final');
                uialert(app.UIFigure, message,'Error');
                
            end 
        end

        % Value changed function: CriteriodeParoDropDown
        function CriteriodeParoDropDownValueChanged(app, event)
            value = app.CriteriodeParoDropDown.Value;
            app.crite_paro_valor = value;
        end

        % Value changed function: MetodoDropDown
        function MetodoDropDownValueChanged(app, event)
            value = app.MetodoDropDown.Value;
            
            if strcmp(value,'Newton-Raphson')
                app.resetTable(value)
                % On
                app.X0EditField.Visible = 'on';
                app.X0EditField_Label.Visible = 'on';
                app.EaEditField.Visible = 'on';
                app.EaEditField_Label.Visible = 'on';
                
                % Off;
                app.XuEditField.Visible = 'off';
                app.XuEditField_Label.Visible = 'off';
                app.XlEditField.Visible = 'off';
                app.XlEditField_Label.Visible = 'off';
                app.ExtremoiEditField.Visible = 'off';
                app.ExtremoiEditField_Label.Visible = 'off';
                app.ExtremofEditField.Visible = 'off';
                app.ExtremofEditField_Label.Visible = 'off';
                app.CriteriodeParoDropDown.Enable = true;
                
                
                app.hEditField.Visible = 'off';
                app.hEditField_Label.Visible = 'off';
                app.XiEditField_Label.Visible = 'off';
                app.XiEditField.Visible = 'off';
                
            elseif strcmp(value,'Biseccion')
                app.resetTable(value)
                % On
                app.XuEditField.Visible = 'on';
                app.XuEditField_Label.Visible = 'on';
                app.XlEditField.Visible = 'on';
                app.XlEditField_Label.Visible = 'on';
                app.X0EditField.Visible = 'on';
                app.X0EditField_Label.Visible = 'on';
                app.EaEditField.Visible = 'on';
                app.EaEditField_Label.Visible = 'on';
                % Off
                app.ExtremoiEditField.Visible = 'off';
                app.ExtremoiEditField_Label.Visible = 'off';
                app.ExtremofEditField.Visible = 'off';
                app.ExtremofEditField_Label.Visible = 'off';
                app.CriteriodeParoDropDown.Enable = true;
                app.XiEditField_Label.Visible = 'off';
                app.XiEditField.Visible = 'off';


                app.hEditField.Visible = 'off';
                app.hEditField_Label.Visible = 'off';

            elseif strcmp(value,'Punto Fijo')
                app.resetTable(value)
                % On
                app.X0EditField.Visible = 'on';
                app.X0EditField_Label.Visible = 'on';
                app.EaEditField.Visible = 'on';
                app.EaEditField_Label.Visible = 'on';
                % Off;
                app.XuEditField.Visible = 'off';
                app.XuEditField_Label.Visible = 'off';
                app.XlEditField.Visible = 'off';
                app.XlEditField_Label.Visible = 'off';
                app.ExtremoiEditField.Visible = 'off';
                app.ExtremoiEditField_Label.Visible = 'off';
                app.ExtremofEditField.Visible = 'off';
                app.ExtremofEditField_Label.Visible = 'off';
                app.CriteriodeParoDropDown.Enable = true;
                
                
                app.hEditField.Visible = 'off';
                app.hEditField_Label.Visible = 'off';
            elseif strcmp(value,'Integral')
                app.resetTable(value)
                % On
                app.ExtremoiEditField.Visible = 'on';
                app.ExtremoiEditField_Label.Visible = 'on';
                app.ExtremofEditField.Visible = 'on';
                app.ExtremofEditField_Label.Visible = 'on';
                % Off;
                
                app.X0EditField.Visible = 'off';
                app.X0EditField_Label.Visible = 'off';
                app.EaEditField.Visible = 'off';
                app.EaEditField_Label.Visible = 'off';
                app.XuEditField.Visible = 'off';
                app.XuEditField_Label.Visible = 'off';
                app.XlEditField.Visible = 'off';
                app.XlEditField_Label.Visible = 'off';
                
                app.hEditField.Visible = 'on';
                app.hEditField_Label.Visible = 'on';
                
                app.CriteriodeParoDropDown.Value = 'Iteraciones';
                app.CriteriodeParoDropDown.Enable = false;
                app.XiEditField_Label.Visible = 'on';
                app.XiEditField.Visible = 'on';
            elseif strcmp(value,'Euler')
                app.resetTable(value)
                % On
                app.ExtremoiEditField.Visible = 'on';
                app.ExtremoiEditField_Label.Visible = 'on';
                app.ExtremofEditField.Visible = 'on';
                app.ExtremofEditField_Label.Visible = 'on';
                % Off;
                
                app.X0EditField.Visible = 'off';
                app.X0EditField_Label.Visible = 'off';
                app.EaEditField.Visible = 'off';
                app.EaEditField_Label.Visible = 'off';
                app.XuEditField.Visible = 'off';
                app.XuEditField_Label.Visible = 'off';
                app.XlEditField.Visible = 'off';
                app.XlEditField_Label.Visible = 'off';
                
                app.hEditField.Visible = 'on';
                app.hEditField_Label.Visible = 'on';
                
                
                app.CriteriodeParoDropDown.Value = 'Iteraciones';
                app.CriteriodeParoDropDown.Enable = false;
                app.XiEditField_Label.Visible = 'on';
                app.XiEditField.Visible = 'on';


            end
            
        end

        % Button pushed function: SolucionarButton
        function SolucionarButtonPushed(app, event)
            f_string = app.FuncionEditField.Value;
            f_sirve = app.funcionSirve(f_string);
            value = app.MetodoDropDown.Value;
            app.resetTable(value)
            if f_sirve
                if strcmp(value,'Newton-Raphson')
                    % On
                    x0 = app.X0EditField.Value;
                    ea = app.EaEditField.Value;
                    % iteraciones max
                    imax = 0;
                    % error maximo
                    es = ea;
                    if strcmp(app.crite_paro_valor,'Iteraciones')
                        imax = app.ValorCriteParoEditField.Value;
                    else
                        es = app.ValorCriteParoEditField.Value;
                    end
                    
                    app.NewtonRaphson(f_string, x0, ea, es, imax)
                    
                elseif strcmp(value,'Biseccion')
                    % On
                    xu = app.XuEditField.Value;
                    xl = app.XlEditField.Value;
                    x0 = app.X0EditField.Value;
                    ea = app.EaEditField.Value;
                    % iteraciones max
                    imax = 0;
                    % error maximo
                    es = ea;
                    
                    if strcmp(app.crite_paro_valor,'Iteraciones')
                        imax = app.ValorCriteParoEditField.Value;
                    else
                        es = app.ValorCriteParoEditField.Value;
                    end
                    
                    app.Bisect(f_string,  xl, xu, x0, ea, es, imax)
                    
    
                elseif strcmp(value,'Punto Fijo')
                    % On
                    x0 = app.X0EditField.Value;
                    ea = app.EaEditField.Value;
                    % iteraciones max
                    imax = 0;
                    % error maximo
                    es = ea;
                    if strcmp(app.crite_paro_valor,'Iteraciones')
                        imax = app.ValorCriteParoEditField.Value;
                    else
                        es = app.ValorCriteParoEditField.Value;
                    end
                    
                    app.Fixpt(f_string, x0, ea, es, imax)
                    
                    
                elseif strcmp(value,'Integral')
                    app.resetTable(value)
                    % On
                    a = app.ExtremoiEditField.Value;
                    b = app.ExtremofEditField.Value;
                    
                    imax = app.ValorCriteParoEditField.Value;
                    
                    app.TrapecioCompuesto(f_string, a, b, imax)
                    
                end
            else
                message = sprintf('La función no puede ser creada');
                uialert(app.UIFigure, message,'Error');
            end
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            new_value = app.EcuacionEditField.Value;
            if ~strcmp(new_value,'')
                oldData = get(app.EcuacionesUITable,'Data');
                newData = { new_value;};
                newData = [oldData; newData];
                
                app.EcuacionesUITable.Data = newData;
            else
                message = sprintf('No puede agregarse la ecuacion');
                uialert(app.UIFigure, message,'Error');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gráfico de la Función')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.PasodelejexEditFieldLabel = uilabel(app.UIFigure);
            app.PasodelejexEditFieldLabel.Text = 'Paso del eje x:';
            app.EditField_paso_ejex = uieditfield(app.UIFigure, 'numeric');
            app.LmiteSuperiorejex_Label = uilabel(app.UIFigure);
            app.LmiteSuperiorejex_Label.Text = 'Límite Superior eje x';
            app.EditField_lim_sup_x = uieditfield(app.UIFigure, 'numeric');
            app.LmiteInferiorejex_Label = uilabel(app.UIFigure);
            app.LmiteInferiorejex_Label.Text = 'Límite Inferior eje x:';
            app.EditField_lim_inf_x = uieditfield(app.UIFigure, 'numeric');
            app.ExtremofEditField_Label = uilabel(app.UIFigure);
            app.ExtremofEditField_Label.Text = 'Extremo_f';
            app.ExtremofEditField = uieditfield(app.UIFigure, 'numeric');
            app.XlEditField_Label = uilabel(app.UIFigure);
            app.XlEditField_Label.Text = 'Xl';
            app.XlEditField = uieditfield(app.UIFigure, 'numeric');
            app.X0EditField_Label = uilabel(app.UIFigure);
            app.X0EditField_Label.Text = 'X0';
            app.X0EditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iteracion'; 'Xl'; 'Xu'; 'Xr'; 'f(Xl)'; 'f(Xr)'; 'f(Xl) * f(Xr)'; 'Ea'};
            app.UITable.RowName = {};
            app.SolucionarButton = uibutton(app.UIFigure, 'push');
            app.SolucionarButton.ButtonPushedFcn = createCallbackFcn(app, @SolucionarButtonPushed, true);
            app.SolucionarButton.Text = 'Solucionar';
            app.ExportarSolucinaExcelButton = uibutton(app.UIFigure, 'push');
            app.ExportarSolucinaExcelButton.Text = 'Exportar Solución a Excel';
            app.SistemadeEcuacionesCheckBox = uicheckbox(app.UIFigure);
            app.SistemadeEcuacionesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SistemadeEcuacionesCheckBoxValueChanged, true);
            app.SistemadeEcuacionesCheckBox.Text = 'Sistema de Ecuaciones';
            app.EcuacionEditFieldLabel = uilabel(app.UIFigure);
            app.EcuacionEditFieldLabel.Text = 'Ecuacion';
            app.EcuacionEditField = uieditfield(app.UIFigure, 'text');
            app.AgregarButton = uibutton(app.UIFigure, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.MetodoSEDropDown_Label = uilabel(app.UIFigure);
            app.MetodoSEDropDown_Label.Text = 'Método';
            app.MetodoSEDropDown = uidropdown(app.UIFigure);
            app.MetodoSEDropDown.Items = {'Gauss-Jordan', 'Descomposicion-LU', 'Inversa'};
            app.MetodoSEDropDown.Value = 'Gauss-Jordan';
            app.EaEditField_Label = uilabel(app.UIFigure);
            app.EaEditField_Label.Text = 'Ea';
            app.EaEditField = uieditfield(app.UIFigure, 'numeric');
            app.EaEditField.Value = 0.0001;
            app.XuEditField_Label = uilabel(app.UIFigure);
            app.XuEditField_Label.Text = 'Xu';
            app.XuEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExtremoiEditField_Label = uilabel(app.UIFigure);
            app.ExtremoiEditField_Label.Text = 'Extremo_i';
            app.ExtremoiEditField = uieditfield(app.UIFigure, 'numeric');
            app.MetodoDropDown_Label = uilabel(app.UIFigure);
            app.MetodoDropDown_Label.Text = 'Método';
            app.MetodoDropDown = uidropdown(app.UIFigure);
            app.MetodoDropDown.Items = {'Biseccion', 'Punto Fijo', 'Newton-Raphson', 'Integral', 'Diferenciación', 'Euler'};
            app.MetodoDropDown.ValueChangedFcn = createCallbackFcn(app, @MetodoDropDownValueChanged, true);
            app.MetodoDropDown.Value = 'Biseccion';
            app.FuncionEditField_Label = uilabel(app.UIFigure);
            app.FuncionEditField_Label.Text = 'Funcion';
            app.FuncionEditField = uieditfield(app.UIFigure, 'text');
            app.CriteriodeParoDropDown_Label = uilabel(app.UIFigure);
            app.CriteriodeParoDropDown_Label.Text = 'Criterio de Paro';
            app.CriteriodeParoDropDown = uidropdown(app.UIFigure);
            app.CriteriodeParoDropDown.Items = {'Iteraciones', 'Error Absoluto'};
            app.CriteriodeParoDropDown.ValueChangedFcn = createCallbackFcn(app, @CriteriodeParoDropDownValueChanged, true);
            app.CriteriodeParoDropDown.Value = 'Iteraciones';
            app.ValorCriteParoEditField_Label = uilabel(app.UIFigure);
            app.ValorCriteParoEditField_Label.Text = 'Valor para el criterio de paro';
            app.ValorCriteParoEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValorCriteParoEditField.Value = 1;
            app.EcuacionesUITable = uitable(app.UIFigure);
            app.EcuacionesUITable.ColumnName = {'Ecuaciones'};
            app.EcuacionesUITable.RowName = {};
            app.hEditField_Label = uilabel(app.UIFigure);
            app.hEditField_Label.Text = 'h';
            app.hEditField = uieditfield(app.UIFigure, 'numeric');
            app.XiEditField_Label = uilabel(app.UIFigure);
            app.XiEditField_Label.Text = 'Xi';
            app.XiEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = proyecto_metodos_numericos

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end